classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        EmergencyLoginButton    matlab.ui.control.Button
        StatusLabel             matlab.ui.control.Label
        LoginButton             matlab.ui.control.Button
        PasswordField           matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        UsernameField           matlab.ui.control.EditField
        UserNameEditFieldLabel  matlab.ui.control.Label
        HumanFallDetectionLoginPortalLabel_2  matlab.ui.control.Label
    end


    properties (Access = private)
        validUsername = 'admin'; % Example username
        validPassword = 'admin'; % Example password
    end

    methods (Access = private)

        % Function to open a new screen
        function openNewScreen(app)
            % Create a new UIFigure for the next screen
            run('real_time_fall_detection_by_matlab.mlapp'); % Run the fall detecting app
            delete(app.UIFigure); % Close the primary app

            % Optionally, add more components or functionality to the new screen
        end

        
        function loginFunction(app)
            username = app.UsernameField.Value;
            password = app.PasswordField.Value;

            if strcmp(username, app.validUsername) && strcmp(password, app.validPassword)
                app.StatusLabel.Text = 'Login successful';
                msgbox("Login successful");
                app.StatusLabel.FontColor = [0 0.5 0]; % Green color
                pause(0.5); % Pause for 1 second to show the success message
                app.openNewScreen(); % Open the new screen
            else
                app.StatusLabel.Text = 'Invalid username or password';
                msgbox("Invalid username or password");
                app.StatusLabel.FontColor = [1 0 0]; % Red color
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            %calling calling function
            loginFunction(app);
        end

        % Button pushed function: EmergencyLoginButton
        function EmergencyLoginButtonPushed(app, event)
           app.openNewScreen(); % Open the new screen
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HumanFallDetectionLoginPortalLabel_2 = uilabel(app.UIFigure);
            app.HumanFallDetectionLoginPortalLabel_2.Text = 'Human Fall Detection Login Portal';
            app.UserNameEditFieldLabel = uilabel(app.UIFigure);
            app.UserNameEditFieldLabel.Text = 'User Name';
            app.UsernameField = uieditfield(app.UIFigure, 'text');
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordField = uieditfield(app.UIFigure, 'text');
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = '';
            app.EmergencyLoginButton = uibutton(app.UIFigure, 'push');
            app.EmergencyLoginButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyLoginButtonPushed, true);
            app.EmergencyLoginButton.Text = 'Emergency Login';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'alert-icon.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end